classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        KonvolusiTab              matlab.ui.container.Tab
        ConvolutedImageLabel      matlab.ui.control.Label
        ConvolutedImage           matlab.ui.control.Image
        InputImageButton          matlab.ui.control.Button
        MaskListLabel             matlab.ui.control.Label
        MaskListImage             matlab.ui.control.Image
        ConvolutionButton         matlab.ui.control.Button
        ConvolutionDropDown       matlab.ui.control.DropDown
        ConvolutionDropDownLabel  matlab.ui.control.Label
        ImfilterImage             matlab.ui.control.Image
        ImfilterImageLabel        matlab.ui.control.Label
        InputImage                matlab.ui.control.Image
        InputImageLabel           matlab.ui.control.Label
        Tab2                      matlab.ui.container.Tab
        Halo2Label                matlab.ui.control.Label
        Tab3                      matlab.ui.container.Tab
        Halo3Label                matlab.ui.control.Label
    end

    
    methods (Access = private)
        function matrix = getSelectedMaskMatrix(app)
            % Define 1st mask
            mask1 = [
                1, 2, 1;
                2, 4, 2;
                1, 2, 1;
            ];
            mask1 = mask1 * (1/16);

            % Define 2nd mask
            mask2 = [
                0, -1, 0;
                -1, 4, -1;
                0, -1, 0;
            ];
            
            % Define 3rd mask
            mask3 = [
                1, 1, 2, 2, 2, 1, 1;
                1, 2, 2, 4, 2, 2, 1;
                2, 2, 4, 8, 4, 2, 2;
                2, 4, 8, 16, 8, 4, 2;
                2, 2, 4, 8, 4, 2, 2;
                1, 2, 2, 4, 2, 2, 1;
                1, 1, 2, 2, 2, 1, 1;
            ];
            mask3 = mask3 * (1/140);

            % Define 4th mask
            mask4 = [
                -1, -1, -1;
                -1, 17, -1;
                -1, -1, -1;
            ];

            % Return based on selected dropdown value
            switch app.ConvolutionDropDown.Value
                case 'Mask 1'
                    matrix = mask1;
                case 'Mask 2'
                    matrix = mask2;
                case 'Mask 3'
                    matrix = mask3;
                case 'Mask 4'
                    matrix = mask4;
            end
        end

        function resultImage = convolute(~, inputImage, kernel)
            inputImage = im2double(inputImage);

            % Get size of input image and kernel
            [imageHeight, imageWidth] = size(inputImage);
            [kernelHeight, kernelWidth] = size(kernel);

            % Initialize resultImage
            resultImage = zeros(imageHeight, imageWidth);

            % Get center of kernel
            kernelCenterRow = floor(kernelHeight / 2) + 1;
            kernelCenterCol = floor(kernelWidth / 2) + 1;

            % Perform convolution
            for i = 1:imageHeight
                for j = 1:imageWidth
                    % Initialize sum
                    sum = 0;

                    for k = 1:kernelHeight
                        for l = 1:kernelWidth
                            % Check if within boundaries of image
                            row = i + k - kernelCenterRow;
                            col = j + l - kernelCenterCol;

                            if row >= 1 && row <= imageHeight && col >= 1 && col <= imageWidth
                                sum = sum + inputImage(row, col) * kernel(k, l);
                            end

                            % If outside of bounds, sum is not increased,
                            % this follows zero padding principle.
                        end
                    end

                    % Update pixel value
                    resultImage(i, j) = sum;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            % Read input image
            inputImage = app.InputImage.ImageSource;
            
            % Do not convolute image if it's empty
            if isempty(inputImage)
                return;
            end

            % Get the selected convolution matrix
            matrix = app.getSelectedMaskMatrix();

            % Convolute the image and then display it
            if ndims(inputImage) == 3
                redChan = app.convolute(inputImage(:,:,1), matrix);
                greenChan = app.convolute(inputImage(:,:,2), matrix);
                blueChan = app.convolute(inputImage(:,:,3), matrix);
                app.ConvolutedImage.ImageSource = cat(3, redChan, greenChan, blueChan);

                redChan = imfilter(inputImage(:,:,1), matrix);
                greenChan = imfilter(inputImage(:,:,2), matrix);
                blueChan = imfilter(inputImage(:,:,3), matrix);
                app.ImfilterImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.ConvolutedImage.ImageSource = app.convolute(inputImage, matrix);
                app.ImfilterImage.ImageSource = imfilter(inputImage, matrix);
            end
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({'*.bmp;*.tif', 'Image Files (*.bmp, *.tif)'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.InputImage.ImageSource = image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KonvolusiTab = uitab(app.TabGroup);
            app.KonvolusiTab.Title = 'Konvolusi';
            app.InputImageLabel = uilabel(app.KonvolusiTab);
            app.InputImageLabel.Text = 'Citra masukan';
            app.InputImage = uiimage(app.KonvolusiTab);
            app.ImfilterImageLabel = uilabel(app.KonvolusiTab);
            app.ImfilterImageLabel.Text = 'Citra hasil konvolusi (imfilter)';
            app.ImfilterImage = uiimage(app.KonvolusiTab);
            app.ConvolutionDropDownLabel = uilabel(app.KonvolusiTab);
            app.ConvolutionDropDownLabel.Text = 'Mask untuk konvolusi';
            app.ConvolutionDropDown = uidropdown(app.KonvolusiTab);
            app.ConvolutionDropDown.Items = {'Mask 1', 'Mask 2', 'Mask 3', 'Mask 4'};
            app.ConvolutionDropDown.Value = 'Mask 1';
            app.ConvolutionButton = uibutton(app.KonvolusiTab, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Konvolusi';
            app.MaskListImage = uiimage(app.KonvolusiTab);
            app.MaskListImage.ImageSource = fullfile(pathToMLAPP, 'MaskList.png');
            app.MaskListLabel = uilabel(app.KonvolusiTab);
            app.MaskListLabel.Text = 'Pilihan mask untuk konvolusi';
            app.InputImageButton = uibutton(app.KonvolusiTab, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Pilih citra masukan';
            app.ConvolutedImage = uiimage(app.KonvolusiTab);
            app.ConvolutedImageLabel = uilabel(app.KonvolusiTab);
            app.ConvolutedImageLabel.Text = 'Citra hasil konvolusi (app.convolute)';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Halo2Label = uilabel(app.Tab2);
            app.Halo2Label.Text = 'Halo2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Halo3Label = uilabel(app.Tab3);
            app.Halo3Label.Text = 'Halo3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end